classdef GUIV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BrowseButton                matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        RunButton                   matlab.ui.control.Button
        AutomatedLabel              matlab.ui.control.Label
        SignalLabel                 matlab.ui.control.Label
        ClassificationToolLabel     matlab.ui.control.Label
        SignalDataEditFieldLabel    matlab.ui.control.Label
        SignalDataEditField         matlab.ui.control.EditField
        SignalCharacteristicsPanel  matlab.ui.container.Panel
        LikelySourceEditFieldLabel  matlab.ui.control.Label
        LikelySourceEditField       matlab.ui.control.EditField
        ModulationTypeLabel         matlab.ui.control.Label
        ModulationTypeEditField     matlab.ui.control.EditField
        WaveformBandwidthLabel      matlab.ui.control.Label
        WaveformBandwidthEditField  matlab.ui.control.EditField
        FrequencyLabel              matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.EditField
        SampleRateEditFieldLabel    matlab.ui.control.Label
        SampleRateEditField         matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signalFile % the filename for the signal data
        LikelySource % Likely Source value
        ModulationType % Modulation Type value
        WaveformBandwidth % Waveform Bandwidth value
        FreqVal % Frequency value
        OpFreqBand % Operation frequency band value
        RecPower % Received power value
        ConfLevel % Confidence level value
        RandomNumber % Random number value
        TextFileArray % Stores the value from the testing text file
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, SignalDataEditField
        function BrowseButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.*');  %open a file browser window 
            fullname = fullfile(filepath, filename);
            app.signalFile = fullname;
            if app.signalFile == ""
                app.signalFile = "Browse for a file";
            else
                app.signalFile = app.signalFile;
            end
            app.SignalDataEditField.Value = app.signalFile;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            fileID = fopen(app.signalFile);
            if fileID == -1
                app.LikelySourceEditField.Value = "No open file";
            else
                %C = textscan(fileID, '%s', 'Delimiter', ',');
                obwFS = app.SampleRateEditField.Value;
                if (obwFS == 0)
                    errordlg('Must Enter a Valid Sample Rate', 'Input Error');
                else
                    sampleRate = uint32(obwFS);
                    
                    try
                        [filepath,name,ext] = fileparts(app.signalFile);
                        if ext == ".txt"
                            % Brian's read in function
                            txt = fileread(app.signalFile);
                            cac = textscan(txt, "%f %f");
                            wave = cat(2,cac{1}, cac{2});
                            realdata = wave(:,1);
                            imaginarydata = wave(:,2);
                            fclose(fileID);
                        elseif ext == ".bin"
                            % Dylan's read in function
                            wave = fread(fileID, Inf, "float");
                            realdata = wave(1:2:end, :);
                            imaginarydata = wave(2:2:end, :);
                            fclose(fileID);   
                        else
                            error('File extension must be either .bin or .txt')
                        end
                    catch err
                        errordlg(err.message);
                        return
                    end
                    
                    try
                    % Plot the Frequency Domain
                    Fs = double(sampleRate);            % Sampling frequency       
                    L = length(realdata);               % Length of signal
                    Y = fft(realdata + 1i*imaginarydata);
                    P2 = abs(Y/L);
                    f = Fs*linspace(-L/2, L/2, L);
                    plot(app.UIAxes2, f, P2) 

                    % Plot the Time Domain
                    plot(app.UIAxes, realdata)
                    catch
                        errordlg("Failed to plot data")
                        return
                    end
                    
                    try
                    % Classify
                        signalMagnitude = sqrt(realdata.^2 + imaginarydata.^2);
                        o = obw(signalMagnitude, sampleRate);
                        if (o > 1e5)
                            classification = "WIFI";
                        else
                            classification = "Bluetooth";
                        end
                        
                        app.LikelySourceEditField.Value = classification;
                    catch
                        errordlg("Failed to classify signal")
                        return
                    end   
                    
                    %celldisp(C);
                    %app.LikelySourceEditField.Value = C{1}{1};
                    %app.ModulationTypeEditField.Value = C{1}{2};
                    %app.WaveformBandwidthEditField.Value = C{1}{3};
                    %app.FrequencyEditField.Value = C{1}{4};
                    %app.OpFrequencyBandEditField.Value = C{1}{5};
                    %app.ReceivedPowerEditField.Value = C{1}{6};
                    %app.ConfidenceLevelEditField.Value = C{1}{7};
                    %app.RandomNumberEditField.Value = "69"; 
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Upload a file containing the signal data'};
            app.BrowseButton.Text = 'Browse';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '490 team logo.PNG';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AutomatedLabel = uilabel(app.UIFigure);
            app.AutomatedLabel.Text = 'Automated ';
            app.SignalLabel = uilabel(app.UIFigure);
            app.SignalLabel.Text = 'Signal';
            app.ClassificationToolLabel = uilabel(app.UIFigure);
            app.ClassificationToolLabel.Text = 'Classification Tool';
            app.SignalDataEditFieldLabel = uilabel(app.UIFigure);
            app.SignalDataEditFieldLabel.Text = 'Signal Data:';
            app.SignalDataEditField = uieditfield(app.UIFigure, 'text');
            app.SignalDataEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.SignalDataEditField.Tooltip = {'Displays the uploaded filename'};
            app.SignalCharacteristicsPanel = uipanel(app.UIFigure);
            app.SignalCharacteristicsPanel.Title = 'Signal Characteristics';
            app.LikelySourceEditFieldLabel = uilabel(app.SignalCharacteristicsPanel);
            app.LikelySourceEditFieldLabel.Text = 'Likely Source:';
            app.LikelySourceEditField = uieditfield(app.SignalCharacteristicsPanel, 'text');
            app.LikelySourceEditField.Editable = 'off';
            app.ModulationTypeLabel = uilabel(app.SignalCharacteristicsPanel);
            app.ModulationTypeLabel.Text = 'Modulation Type:';
            app.ModulationTypeEditField = uieditfield(app.SignalCharacteristicsPanel, 'text');
            app.ModulationTypeEditField.Editable = 'off';
            app.WaveformBandwidthLabel = uilabel(app.SignalCharacteristicsPanel);
            app.WaveformBandwidthLabel.Text = 'Waveform Bandwidth:';
            app.WaveformBandwidthEditField = uieditfield(app.SignalCharacteristicsPanel, 'text');
            app.WaveformBandwidthEditField.Editable = 'off';
            app.FrequencyLabel = uilabel(app.SignalCharacteristicsPanel);
            app.FrequencyLabel.Text = 'Frequency:';
            app.FrequencyEditField = uieditfield(app.SignalCharacteristicsPanel, 'text');
            app.FrequencyEditField.Editable = 'off';
            app.SampleRateEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate:';
            app.SampleRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frequency Domain')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end